classdef Compare_Iris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FakeIrisdetectionUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        SnapshortMenu              matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        GotoMenu                   matlab.ui.container.Menu
        LivenessMenu               matlab.ui.container.Menu
        Temp_genMenu               matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        AboutProjectMenu           matlab.ui.container.Menu
        AboutApplicationMenu       matlab.ui.container.Menu
        ContactusMenu              matlab.ui.container.Menu
        LoadImageButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        GenerateTemplateButton     matlab.ui.control.Button
        CompareButton              matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        Button                     matlab.ui.control.Button
        Label_3                    matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GenerateTemplateButton.Visible = 'off';
            app.CompareButton.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [FileName, FilePath]= uigetfile('*.*', 'Select a file');
            if isnumeric(FileName); return; end
            a= imread(fullfile(FilePath, FileName));
            setappdata(app.UIAxes,'IrisImg',a);
            imshow(a, 'Parent' , app.UIAxes);
            imshow(a, 'Parent' , app.UIAxes_2);
            app.Label.Text = "Original Image";
            app.UIAxes_2.Visible = 'on';
            app.GenerateTemplateButton.Visible = 'on'; 
            app.CompareButton.Visible = 'off';
        end

        % Button pushed function: GenerateTemplateButton
        function GenerateTemplateButtonPushed(app, event)
            fig=getappdata(app.UIAxes,'IrisImg');
            try
                [local xc yc time]=localisation2(fig,0.2);
                [ci cp out time]=thresh(local,50,200);        
                [ring,parr]=normaliseiris(local,ci(2),ci(1),ci(3),cp(2),cp(1),cp(3),'normal.bmp',100,300);
                [temp th tv]=gen_templateVVV(parr);
                setappdata(app.UIAxes_2,'gen_temp',temp);
                imshow(temp, 'Parent' , app.UIAxes_2);
                app.Label.Text = "Iris Code";
                app.CompareButton.Visible = 'on';
            catch
                uialert(app.FakeIrisdetectionUIFigure,'No Image to Process.','Unsucessful');
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            f = app.FakeIrisdetectionUIFigure;
            match=0;
            try
            temp1 = getappdata(app.UIAxes_2,'gen_temp');
            loc=pwd;
            b=strcat(loc,'\Templates\*.bmp');
            a=dir(b);
            [n m k] = size(temp1);
            key = keyGen(n*m);
            out=size(a,1);
            for k = 1:out   
                filename=strcat('temp',num2str(k));
                location=[loc,'\Templates\',filename,'.bmp'];
                temp2=imread(strcat(location));
                DecImg = imageProcess(temp2,key);
                hd=hammingdist(temp1, DecImg);
                if(hd<=0.2)
                    match=match+1;
                    break
                else
                 match=match*1; 
                end
            end
            if(match>=1)
                text = strcat('Hamming Distance=',string(hd));
                app.Label.Text = text;
                app.Label_2.Text = 'They are same eyes !!!';
                uialert(f,'They are same eyes !!!','Validate',...
                'Icon','success');
            else
                text = strcat('Hamming Distance=',string(hd));
                app.Label.Text = text;
                app.Label_2.Text = 'They are not same eyes !!!';
                uialert(f,'They are not same eyes !!!','Not-Validate');
            end
            catch
                uialert(app.FakeIrisdetectionUIFigure,'No Image to Compare.','Unsucessful');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           delete(app)
           run('Index.mlapp')
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            delete(app)
           run('Compare_Iris.mlapp')
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [FileName, FilePath]= uigetfile('*.*', 'Select a file');
            if isnumeric(FileName); return; end
            a= imread(fullfile(FilePath, FileName));
            setappdata(app.UIAxes,'IrisImg',a);
            imshow(a, 'Parent' , app.UIAxes);
            imshow(a, 'Parent' , app.UIAxes_2);
            app.Label.Text = "Original Image"
        end

        % Menu selected function: AboutApplicationMenu
        function AboutApplicationMenuSelected(app, event)
            message = 'MATLAB (matrix laboratory) is a multi-paradigm numerical computing environment and language developed by Math Works. MATLAB allows matrix manipulations, plotting of functions and data, implementation of algorithms, creation of user interfaces, and interfacing with programs written in other language, including C, C++, C#, Java, Fortran and Python. The MATLAB application is built around the MATLAB scripting language. Common usage of the MATLAB application involves using the Command Window as an interactive mathematical shell or executing text files containing MATLAB code.';
            uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info');
        end

        % Menu selected function: AboutProjectMenu
        function AboutProjectMenuSelected(app, event)
             message='Iris recognition is an automated method of biometric identification that uses mathematical pattern-recognition techniques on video images of the irises of an individuals eyes, whose complex random patterns are unique and can be seen from some distance. Now days Iris is being used widely by several organizations (including governments) for identification and authentication purposes. Most of the work done in the area of Iris pattern recognition systems emphasizes only on matching of the patterns with the stored templates. Security aspects of the system are still unexplored. The available security algorithms provide only some cryptographic solutions that keeps the template database in a secret cryptographic form. We will implement the detection of fake iris images and add the provision of detection of false of scanned iris images as template. This enhanced significantly the performance of the system in terms of security and reliability. We will be using Flash and motion detection of natural eye to detect the liveliness of real iris images before matching from stored templates.';
             uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info')
        end

        % Menu selected function: ContactusMenu
        function ContactusMenuSelected(app, event)
             message='NATIONAL COLLEGE OF ENGINEERING (Affiliated to Tribhuvan University)ASHISH KHANAL	[072/BCT/106]BIKESH BASNET	[072/BCT/109]ISHWOR BOGATI	[072/BCT/116]JEEVAN POUDEL	[072/BCT/117],Talchhikhel, Lalitpur';
           uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info');
        end

        % Menu selected function: Temp_genMenu
        function Temp_genMenuSelected(app, event)
            delete(app)
            run('Tamplate_gen.mlapp')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FakeIrisdetectionUIFigure = uifigure;
            app.FakeIrisdetectionUIFigure.Name = 'Fake Iris detection';
            app.FileMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SnapshortMenu = uimenu(app.FileMenu);
            app.SnapshortMenu.Text = 'Snapshort';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GotoMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.GotoMenu.Text = 'Goto';
            app.LivenessMenu = uimenu(app.GotoMenu);
            app.LivenessMenu.Text = 'Liveness';
            app.Temp_genMenu = uimenu(app.GotoMenu);
            app.Temp_genMenu.MenuSelectedFcn = createCallbackFcn(app, @Temp_genMenuSelected, true);
            app.Temp_genMenu.Text = 'Temp_gen';
            app.HelpMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutProjectMenu = uimenu(app.HelpMenu);
            app.AboutProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutProjectMenuSelected, true);
            app.AboutProjectMenu.Accelerator = 'h';
            app.AboutProjectMenu.Text = 'About Project';
            app.AboutApplicationMenu = uimenu(app.HelpMenu);
            app.AboutApplicationMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutApplicationMenuSelected, true);
            app.AboutApplicationMenu.Accelerator = 'j';
            app.AboutApplicationMenu.Text = 'About Application';
            app.ContactusMenu = uimenu(app.HelpMenu);
            app.ContactusMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactusMenuSelected, true);
            app.ContactusMenu.Separator = 'on';
            app.ContactusMenu.Text = 'Contact us';
            app.LoadImageButton = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.FakeIrisdetectionUIFigure);
            app.UIAxes_2 = uiaxes(app.FakeIrisdetectionUIFigure);
            app.Panel = uipanel(app.FakeIrisdetectionUIFigure);
            app.GenerateTemplateButton = uibutton(app.Panel, 'push');
            app.GenerateTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTemplateButtonPushed, true);
            app.GenerateTemplateButton.Text = 'Generate Template';
            app.CompareButton = uibutton(app.Panel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.Label = uilabel(app.FakeIrisdetectionUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.FakeIrisdetectionUIFigure);
            app.Label_2.Text = '';
            app.Panel_2 = uipanel(app.FakeIrisdetectionUIFigure);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'back.png';
            app.Button.Text = '';
            app.Label_3 = uilabel(app.FakeIrisdetectionUIFigure);
            app.Label_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Compare_Iris

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FakeIrisdetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FakeIrisdetectionUIFigure)
        end
    end
end