classdef Index2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FakeIrisdetectionUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        SnapshortMenu              matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        Menu_2                     matlab.ui.container.Menu
        Menu2_2                    matlab.ui.container.Menu
        Menu3                      matlab.ui.container.Menu
        Menu4                      matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        AboutProjectMenu           matlab.ui.container.Menu
        AboutApplicationMenu       matlab.ui.container.Menu
        ContactusMenu              matlab.ui.container.Menu
        Panel                      matlab.ui.container.Panel
        Button_5                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        IrisSystemPanel            matlab.ui.container.Panel
        GenerateTemplatesButton    matlab.ui.control.Button
        CompareIrisButton          matlab.ui.control.Button
        LivenessButton             matlab.ui.control.Button
        Version10Label             matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             message='Liveness Detected !!';
              uialert(app.FakeIrisdetectionUIFigure,message,'Validate',...
                'Icon','success');
        end

        % Button pushed function: GenerateTemplatesButton
        function GenerateTemplatesButtonPushed(app, event)
            delete(app)
           run('Tamplate_gen.mlapp')
        end

        % Button pushed function: CompareIrisButton
        function CompareIrisButtonPushed(app, event)
            delete(app)
           run('Compare_Iris.mlapp')
        end

        % Menu selected function: AboutProjectMenu
        function AboutProjectMenuSelected(app, event)
            message='Iris recognition is an automated method of biometric identification that uses mathematical pattern-recognition techniques on video images of the irises of an individuals eyes, whose complex random patterns are unique and can be seen from some distance. Now days Iris is being used widely by several organizations (including governments) for identification and authentication purposes. Most of the work done in the area of Iris pattern recognition systems emphasizes only on matching of the patterns with the stored templates. Security aspects of the system are still unexplored. The available security algorithms provide only some cryptographic solutions that keeps the template database in a secret cryptographic form. We will implement the detection of fake iris images and add the provision of detection of false of scanned iris images as template. This enhanced significantly the performance of the system in terms of security and reliability. We will be using Flash and motion detection of natural eye to detect the liveliness of real iris images before matching from stored templates.';
             uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info');
        end

        % Menu selected function: AboutApplicationMenu
        function AboutApplicationMenuSelected(app, event)
            message = 'MATLAB (matrix laboratory) is a multi-paradigm numerical computing environment and language developed by Math Works. MATLAB allows matrix manipulations, plotting of functions and data, implementation of algorithms, creation of user interfaces, and interfacing with programs written in other language, including C, C++, C#, Java, Fortran and Python. The MATLAB application is built around the MATLAB scripting language. Common usage of the MATLAB application involves using the Command Window as an interactive mathematical shell or executing text files containing MATLAB code.';
            uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info');
        end

        % Menu selected function: ContactusMenu
        function ContactusMenuSelected(app, event)
           message='NATIONAL COLLEGE OF ENGINEERING (Affiliated to Tribhuvan University)ASHISH KHANAL	[072/BCT/106]BIKESH BASNET	[072/BCT/109]ISHWOR BOGATI	[072/BCT/116]JEEVAN POUDEL	[072/BCT/117],Talchhikhel, Lalitpur';
           uialert(app.FakeIrisdetectionUIFigure,message,'About Project',...
                'Icon','info');
        end

        % Button pushed function: LivenessButton
        function LivenessButtonPushed(app, event)
          
           run('live.m')
           delete(app)
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
             run('Index2.mlapp')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FakeIrisdetectionUIFigure = uifigure;
            app.FakeIrisdetectionUIFigure.Name = 'Fake Iris detection';
            app.FileMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Accelerator = 'o';
            app.LoadMenu.Text = 'Load';
            app.SnapshortMenu = uimenu(app.FileMenu);
            app.SnapshortMenu.Text = 'Snapshort';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'x';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu_2 = uimenu(app.EditMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.EditMenu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3 = uimenu(app.EditMenu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.EditMenu);
            app.Menu4.Text = 'Menu4';
            app.HelpMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutProjectMenu = uimenu(app.HelpMenu);
            app.AboutProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutProjectMenuSelected, true);
            app.AboutProjectMenu.Accelerator = 'h';
            app.AboutProjectMenu.Text = 'About Project';
            app.AboutApplicationMenu = uimenu(app.HelpMenu);
            app.AboutApplicationMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutApplicationMenuSelected, true);
            app.AboutApplicationMenu.Accelerator = 'j';
            app.AboutApplicationMenu.Text = 'About Application';
            app.ContactusMenu = uimenu(app.HelpMenu);
            app.ContactusMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactusMenuSelected, true);
            app.ContactusMenu.Separator = 'on';
            app.ContactusMenu.Text = 'Contact us';
            app.Panel = uipanel(app.FakeIrisdetectionUIFigure);
            app.Button_5 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_3 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_3.Icon = 'icon.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_4.Text = '';
            app.IrisSystemPanel = uipanel(app.FakeIrisdetectionUIFigure);
            app.IrisSystemPanel.Title = 'Iris System';
            app.GenerateTemplatesButton = uibutton(app.IrisSystemPanel, 'push');
            app.GenerateTemplatesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTemplatesButtonPushed, true);
            app.GenerateTemplatesButton.Text = 'Generate Templates';
            app.CompareIrisButton = uibutton(app.IrisSystemPanel, 'push');
            app.CompareIrisButton.ButtonPushedFcn = createCallbackFcn(app, @CompareIrisButtonPushed, true);
            app.CompareIrisButton.Text = 'Compare Iris';
            app.LivenessButton = uibutton(app.IrisSystemPanel, 'push');
            app.LivenessButton.ButtonPushedFcn = createCallbackFcn(app, @LivenessButtonPushed, true);
            app.LivenessButton.Text = 'Liveness';
            app.Version10Label = uilabel(app.IrisSystemPanel);
            app.Version10Label.Text = '|  Version : 1.0  ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Index2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FakeIrisdetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FakeIrisdetectionUIFigure)
        end
    end
end