classdef Iris_index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FakeIrisdetectionUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewMenu                    matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        SnapshortMenu              matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        Menu_2                     matlab.ui.container.Menu
        Menu2_2                    matlab.ui.container.Menu
        Menu3                      matlab.ui.container.Menu
        Menu4                      matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        Menu_3                     matlab.ui.container.Menu
        Menu2_3                    matlab.ui.container.Menu
        Menu3_2                    matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        Menu_4                     matlab.ui.container.Menu
        Menu2_4                    matlab.ui.container.Menu
        Menu3_3                    matlab.ui.container.Menu
        Menu4_2                    matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        IrisSystemPanel            matlab.ui.container.Panel
        GenerateTemplatesButton    matlab.ui.control.Button
        CompareIrisButton          matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        Button_5                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function Panel_2SizeChanged(app, event)
            [FileName, FilePath]= uigetfile('E:\New folder\test\icon.png');
            a= imread(fullfile(FilePath, FileName));  
            imshow(a, 'Parent' , app.UIAxes);
        end

        % Button pushed function: GenerateTemplatesButton
        function GenerateTemplatesButtonPushed(app, event)
           delete(app)
           run('Tamplate_gen.mlapp')
        end

        % Button pushed function: CompareIrisButton
        function CompareIrisButtonPushed(app, event)
            delete(app)
           run('Compare_Iris.mlapp')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FakeIrisdetectionUIFigure = uifigure;
            app.FakeIrisdetectionUIFigure.Name = 'Fake Iris detection';
            app.FileMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SnapshortMenu = uimenu(app.FileMenu);
            app.SnapshortMenu.Text = 'Snapshort';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu_2 = uimenu(app.EditMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.EditMenu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3 = uimenu(app.EditMenu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.EditMenu);
            app.Menu4.Text = 'Menu4';
            app.HelpMenu = uimenu(app.FakeIrisdetectionUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Menu_3 = uimenu(app.HelpMenu);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.HelpMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.HelpMenu);
            app.Menu3_2.Text = 'Menu3';
            app.Menu = uimenu(app.FakeIrisdetectionUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.Menu);
            app.Menu2_4.Text = 'Menu2';
            app.Menu3_3 = uimenu(app.Menu);
            app.Menu3_3.Text = 'Menu3';
            app.Menu4_2 = uimenu(app.Menu);
            app.Menu4_2.Text = 'Menu4';
            app.Menu2 = uimenu(app.FakeIrisdetectionUIFigure);
            app.Menu2.Text = 'Menu2';
            app.IrisSystemPanel = uipanel(app.FakeIrisdetectionUIFigure);
            app.IrisSystemPanel.Title = 'Iris System';
            app.GenerateTemplatesButton = uibutton(app.IrisSystemPanel, 'push');
            app.GenerateTemplatesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTemplatesButtonPushed, true);
            app.GenerateTemplatesButton.Text = 'Generate Templates';
            app.CompareIrisButton = uibutton(app.IrisSystemPanel, 'push');
            app.CompareIrisButton.ButtonPushedFcn = createCallbackFcn(app, @CompareIrisButtonPushed, true);
            app.CompareIrisButton.Text = 'Compare Iris';
            app.Button_3 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_2.Text = '';
            app.Button_4 = uibutton(app.FakeIrisdetectionUIFigure, 'push');
            app.Button_4.Icon = '348910.jpg';
            app.Button_4.Text = '';
            app.Panel = uipanel(app.FakeIrisdetectionUIFigure);
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.Icon = 'back.png';
            app.Button_5.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Iris_index

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FakeIrisdetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FakeIrisdetectionUIFigure)
        end
    end
end