classdef liveness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        Label     matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            obj = VideoReader('irisavi.avi');
            x1=0;
            NumberOfFrames = obj.NumberOfFrames;
    
            la_imagen=read(obj,10);
            filas=size(la_imagen,1);
            columnas=size(la_imagen,2);
            % Center
            centro_fila=round(filas/2);
            centro_columna=round(columnas/2);
            for cnt = 1:NumberOfFrames       
                la_imagen=read(obj,cnt);
                if size(la_imagen,3)==3
                    la_imagen=rgb2gray(la_imagen);
                end

           
            piel=~im2bw(la_imagen,0.19);
    %     --
    %piel=bwmorph(piel,'close');
    %piel=bwmorph(piel,'open');
    %piel=bwareaopen(piel,200);
    %piel=imfill(piel,'holes');
    %imagesc(piel);
    % Tagged objects in BW image
    L=bwlabel(piel);
    % Get areas and tracking rectangle
    out_a=regionprops(L);
    % Count the number of objects
    N=size(out_a,1);
    if N < 1 || isempty(out_a) % Returns if no object in the image
        solo_cara=[ ];
        continue
    end
    % ---
    % Select larger area
    areas=[out_a.Area];
    [area_max pam]=max(areas);
    imshow(la_imagen, 'Parent' , app.UIAxes);
    %imagesc(la_imagen);
    colormap gray
    hold on
    %rectangle('Position',out_a(pam).BoundingBox,'EdgeColor',[1 0 0],...
     %   'Curvature', [1,1],'LineWidth',2)
    centro=round(out_a(pam).Centroid);
    X=centro(1);

    try
        if (X~=x1)
            app.Label.Text = "live Image";
            x1=X;
        end
    catch
              continue

    end
   % Y=centro(2);
   
    %     
    %text(X+10,Y,['(',num2str(X),',',num2str(Y),')'],'Color',[1 1 1])
    %if X<centro_columna && Y<centro_fila
     %   title('Top left')
    %elseif X>centro_columna && Y<centro_fila
      %  title('Top right')
    %elseif X<centro_columna && Y>centro_fila
     %   title('Bottom left')
    %else
     %   title('Bottom right')
    %end
    hold off
    % --
    drawnow;
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = liveness

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end